classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AlgoritmoButtonGroup       matlab.ui.container.ButtonGroup
        DTButton                   matlab.ui.control.ToggleButton
        SVMButton                  matlab.ui.control.ToggleButton
        KNNButton                  matlab.ui.control.ToggleButton
        ANNButton                  matlab.ui.control.ToggleButton
        TabGroup                   matlab.ui.container.TabGroup
        QuadTreeTab                matlab.ui.container.Tab
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        RegionPropsTab_2           matlab.ui.container.Tab
        NegativaCheckBox           matlab.ui.control.CheckBox
        LevelEditField             matlab.ui.control.NumericEditField
        LevelEditFieldLabel        matlab.ui.control.Label
        IdentificacionButtonGroup  matlab.ui.container.ButtonGroup
        AmbosButton                matlab.ui.control.ToggleButton
        QuadtreeButton             matlab.ui.control.ToggleButton
        RegionPropsButton          matlab.ui.control.ToggleButton
        ApagarButton               matlab.ui.control.ToggleButton
        ColoresListBox             matlab.ui.control.ListBox
        ColoresListBoxLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Repeat = true; % Description
        Pause = 0.5; 

        Algo = 'ann'; % Description

        ResizeQT = 512; 
        ThresholdQT = 0.5; 
        DimsQT = [512 256 128 64]; 


        NegativeRP = true; 
        LevelRP = 0.2; 
        
    end
    
    
    methods (Access = private)
        function img = capture(app)
            cam = webcam('Integrated');
            img = snapshot(cam); 
        end
        function powerup_quadtree(app)
            clc; 
            app.Repeat = true;
            app.UIAxes.Title.String='QuadTree';

            while app.Repeat

                img = capture(app); 

                try
                    tic
                        predictions = app_predict_quadtree(img,app.ResizeQT,app.ThresholdQT, app.DimsQT,app.Algo, app.UIAxes);
                    toc
    
                    app.ColoresListBox.Items = unique(predictions(:,2));
                catch
                end
                pause(app.Pause);

            end
        end
        
        function powerup_regionprops(app)
        
            clc; 
           
            app.Repeat = true; 
            app.UIAxes.Title.String='RegionProps';
            
            while app.Repeat
    
                img = capture(app); 
                try
                    tic
                        predictions = app_predict_regionprops(img, app.Algo , app.NegativeRP , app.LevelRP, app.UIAxes); 
                        fprintf(app.Algo); 
                    toc
    
                    app.ColoresListBox.Items = unique(predictions(:,2));
                catch
                end
                pause(app.Pause);

            end
        end
        
        function results = powerup_both(app)
            
            clc; 
           
            app.Repeat = true; 
            app.UIAxes.Title.String='RegionProps';
            
            while app.Repeat
    
                img = capture(app); 
                try
                    tic
                        predictions = app_predict_regionprops_quadtree(img, app.UIAxes, app.Algo , app.ThresholdQT, app.NegativeRP); 
                        fprintf(app.Algo); 
                    toc
    
                    app.ColoresListBox.Items = unique(predictions(:,2));
                catch
                end
                pause(app.Pause);

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: IdentificacionButtonGroup
        function IdentificacionButtonGroupSelectionChanged(app, event)
            selectedButton = app.IdentificacionButtonGroup.SelectedObject;
            app.Repeat = false; 
            switch selectedButton.Text
                case 'RegionProps'
                    powerup_regionprops(app);
                case 'Quadtree'
                    powerup_quadtree(app);
                case 'Ambos'
                    powerup_both(app); 
                otherwise
                    app.Repeat = false; 
                    app.UIAxes.Visible = "off"; 
            end
            
        end

        % Selection changed function: AlgoritmoButtonGroup
        function AlgoritmoButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgoritmoButtonGroup.SelectedObject;
            app.UIAxes.Visible = "off"; 
            switch selectedButton.Text
                case 'ANN'
                    app.Algo = 'ann'; 
                case 'KNN'
                    app.Algo = 'knn'; 
                case 'SVM'
                    app.Algo = 'svm'; 
                case 'DT'
                    app.Algo = 'dt'; 
                otherwise 
                    app.Algo = 'ann'; 
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.ThresholdQT = value; 
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;
            app.LevelRP = value; 
        end

        % Value changed function: NegativaCheckBox
        function NegativaCheckBoxValueChanged(app, event)
            value = app.NegativaCheckBox.Value;
            if value 
                app.NegativeRP = true; 
            else
                app.NegativeRP = false; 
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ColoresListBoxLabel = uilabel(app.UIFigure);
            app.ColoresListBoxLabel.Text = 'Colores';
            app.ColoresListBox = uilistbox(app.UIFigure);
            app.IdentificacionButtonGroup = uibuttongroup(app.UIFigure);
            app.IdentificacionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IdentificacionButtonGroupSelectionChanged, true);
            app.IdentificacionButtonGroup.Title = 'Identificacion';
            app.ApagarButton = uitogglebutton(app.IdentificacionButtonGroup);
            app.ApagarButton.Text = 'Apagar';
            app.ApagarButton.Value = true;
            app.RegionPropsButton = uitogglebutton(app.IdentificacionButtonGroup);
            app.RegionPropsButton.Text = 'RegionProps';
            app.QuadtreeButton = uitogglebutton(app.IdentificacionButtonGroup);
            app.QuadtreeButton.Text = 'Quadtree';
            app.AmbosButton = uitogglebutton(app.IdentificacionButtonGroup);
            app.AmbosButton.Text = 'Ambos';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QuadTreeTab = uitab(app.TabGroup);
            app.QuadTreeTab.Title = 'QuadTree';
            app.ThresholdEditFieldLabel = uilabel(app.QuadTreeTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.QuadTreeTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.5;
            app.RegionPropsTab_2 = uitab(app.TabGroup);
            app.RegionPropsTab_2.Title = 'RegionProps';
            app.LevelEditFieldLabel = uilabel(app.RegionPropsTab_2);
            app.LevelEditFieldLabel.Text = 'Level';
            app.LevelEditField = uieditfield(app.RegionPropsTab_2, 'numeric');
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Value = 0.2;
            app.NegativaCheckBox = uicheckbox(app.RegionPropsTab_2);
            app.NegativaCheckBox.ValueChangedFcn = createCallbackFcn(app, @NegativaCheckBoxValueChanged, true);
            app.NegativaCheckBox.Text = 'Negativa';
            app.AlgoritmoButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgoritmoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgoritmoButtonGroupSelectionChanged, true);
            app.AlgoritmoButtonGroup.Title = 'Algoritmo';
            app.ANNButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.ANNButton.Text = 'ANN';
            app.ANNButton.Value = true;
            app.KNNButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.SVMButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.DTButton = uitogglebutton(app.AlgoritmoButtonGroup);
            app.DTButton.Text = 'DT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end